classdef fin_temperature_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PredictTemperatureButton  matlab.ui.control.Button
        FinLengthmmSlider         matlab.ui.control.Slider
        FinLengthmmSliderLabel    matlab.ui.control.Label
        ErrorAxes                 matlab.ui.control.UIAxes
        FinAxes                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictTemperatureButton
        function PredictTemperatureButtonPushed(app, event)
            % Give all three plots enough space
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure.Position = [50 50 900 800];

            % Top: coloured fin
            app.FinAxes.Position = [90 620 650 160];

            % Middle: temperature comparison
            app.UIAxes.Position = [75 360 780 240];

            % Bottom: prediction errors, including legend
            app.ErrorAxes.Position = [75 120 780 210];

            % Controls below the plots
            app.FinLengthmmSliderLabel.Position = [20 75 120 22];
            app.FinLengthmmSlider.Position = [160 85 650 3];
            app.PredictTemperatureButton.Position = [350 20 200 32];
            % Get the selected fin length
            L_mm = app.FinLengthmmSlider.Value;
            L = L_mm / 1000;
            x = linspace(0, L, 100)';

            % Load the trained models
            baseline = load('fin_surrogate_model.mat');
            pinn = load('fin_pinn_model.mat');

            % Calculate the analytical reference
            h = pinn.h;
            k = pinn.k;
            m = sqrt(pinn.m2);

            theory = (cosh(m * (L - x)) + ...
                (h / (m * k)) * sinh(m * (L - x))) ./ ...
                (cosh(m * L) + (h / (m * k)) * sinh(m * L));

            % Predict using the baseline neural network
            X = [x, L * ones(size(x))];
            Xscaled = (X - baseline.xmin) ./ baseline.xrange;
            dlX = dlarray(single(Xscaled'), 'CB');

            baselinePrediction = predict(baseline.net, dlX);
            baselinePrediction = double(gather( ...
                extractdata(baselinePrediction)));
            baselinePrediction = baselinePrediction(:);

            % Predict using the PINN
            Xpinn = [x / L, (L / pinn.Lref) * ones(size(x))];
            dlXpinn = dlarray(single(Xpinn'), 'CB');

            pinnPrediction = predict(pinn.net, dlXpinn);
            pinnPrediction = double(gather( ...
                extractdata(pinnPrediction)));
            pinnPrediction = pinnPrediction(:);

            % Calculate errors for this selected fin length
            baselineRMSE = sqrt(mean((baselinePrediction - theory).^2));
            pinnRMSE = sqrt(mean((pinnPrediction - theory).^2));

            % Clear the previous graph
            cla(app.UIAxes);
            app.UIAxes.Color = [1 1 1];

            % Draw the analytical reference
            plot(app.UIAxes, x * 1000, theory, ...
                'k-', 'LineWidth', 2.5);
            hold(app.UIAxes, 'on');

            % Draw the baseline prediction
            plot(app.UIAxes, x * 1000, baselinePrediction, ...
                'b--', 'LineWidth', 2);

            % Draw the PINN prediction
            plot(app.UIAxes, x * 1000, pinnPrediction, ...
                'r-.', 'LineWidth', 2);

            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');

            % Add labels and error values
            xlabel(app.UIAxes, 'Position along fin (mm)');
            ylabel(app.UIAxes, 'Normalized temperature ratio');

            title(app.UIAxes, { ...
                sprintf('Fin length: %.1f mm', L_mm), ...
                sprintf('RMSE at this length: Baseline %.4f | PINN %.4f', ...
                baselineRMSE, pinnRMSE)});

            legend(app.UIAxes, ...
                'Analytical reference', ...
                'Baseline neural network', ...
                'PINN', ...
                'Location', 'best');

            xlim(app.UIAxes, [0 L_mm]);
            % Show the PINN temperature prediction as a colored fin
            cla(app.FinAxes);

            surface(app.FinAxes, ...
                [x'; x'] * 1000, ...
                [zeros(1,numel(x)); ones(1,numel(x))], ...
                zeros(2,numel(x)), ...
                [pinnPrediction'; pinnPrediction'], ...
                'FaceColor', 'interp', 'EdgeColor', 'none');

            view(app.FinAxes, 2);

            % Fixed colors: blue = cooler, red = hotter
            colormap(app.FinAxes, [ ...
                linspace(0,1,256)', ...
                zeros(256,1), ...
                linspace(1,0,256)']);
            app.FinAxes.CLim = [0 1];

            xlim(app.FinAxes, [0 70]);
            ylim(app.FinAxes, [-0.7 1.7]);
            app.FinAxes.XTick = [];
            app.FinAxes.YTick = [];
            xlabel(app.FinAxes, '');
            ylabel(app.FinAxes, '');
            grid(app.FinAxes, 'off');

            title(app.FinAxes, ...
                'Where is the fin hotter? PINN prediction');

            text(app.FinAxes, 0, -0.35, 'Hot base', ...
                'Color', [0.9 0.9 0.9], ...
                'HorizontalAlignment', 'left');

            text(app.FinAxes, L_mm, -0.35, 'Tip', ...
                'Color', [0.9 0.9 0.9], ...
                'HorizontalAlignment', 'right');

            cb = colorbar(app.FinAxes);
            cb.Ticks = [0 1];
            cb.TickLabels = {'Surroundings', 'Base temperature'};
            % Calculate prediction errors
            baselineError = baselinePrediction - theory;
            pinnError = pinnPrediction - theory;

            % Draw the error plot
            cla(app.ErrorAxes);
            app.ErrorAxes.Color = [1 1 1];

            plot(app.ErrorAxes, x * 1000, baselineError, ...
                'b-', 'LineWidth', 2);
            hold(app.ErrorAxes, 'on');

            plot(app.ErrorAxes, x * 1000, pinnError, ...
                'r--', 'LineWidth', 2);

            yline(app.ErrorAxes, 0, 'k:', 'LineWidth', 1.5);
            hold(app.ErrorAxes, 'off');

            grid(app.ErrorAxes, 'on');
            xlim(app.ErrorAxes, [0 L_mm]);

            title(app.ErrorAxes, 'Closer to zero = smaller error');
            xlabel(app.ErrorAxes, 'Position along fin (mm)');
            ylabel(app.ErrorAxes, 'Prediction error');

            legend(app.ErrorAxes, 'Baseline', 'PINN', ...
                'Perfect prediction', ...
                'Location', 'southoutside', ...
                'Orientation', 'horizontal', ...
                'FontSize', 9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fin Temperature Prediction')
            xlabel(app.UIAxes, 'Position along fin(mm)')
            ylabel(app.UIAxes, 'Normlaized temperature ratio')
            zlabel(app.UIAxes, 'Z')
            app.FinAxes = uiaxes(app.UIFigure);
            title(app.FinAxes, 'Title')
            xlabel(app.FinAxes, 'X')
            ylabel(app.FinAxes, 'Y')
            zlabel(app.FinAxes, 'Z')
            app.ErrorAxes = uiaxes(app.UIFigure);
            title(app.ErrorAxes, 'Title')
            xlabel(app.ErrorAxes, 'X')
            ylabel(app.ErrorAxes, 'Y')
            zlabel(app.ErrorAxes, 'Z')
            app.FinLengthmmSliderLabel = uilabel(app.UIFigure);
            app.FinLengthmmSliderLabel.Text = 'Fin Length (mm)';
            app.FinLengthmmSlider = uislider(app.UIFigure);
            app.FinLengthmmSlider.Limits = [10 70];
            app.FinLengthmmSlider.MajorTicks = [10 20 30 40 50 60 70];
            app.FinLengthmmSlider.Value = 30;
            app.PredictTemperatureButton = uibutton(app.UIFigure, 'push');
            app.PredictTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @PredictTemperatureButtonPushed, true);
            app.PredictTemperatureButton.Text = 'Predict Temperature';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fin_temperature_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end